classdef SerialVisualizator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ImpostazioniMenu      matlab.ui.container.Menu
        PathsMenu             matlab.ui.container.Menu
        CmakeMenu             matlab.ui.container.Menu
        MakeMenu              matlab.ui.container.Menu
        ArmNoneEabiMenu       matlab.ui.container.Menu
        GccMenu               matlab.ui.container.Menu
        OpenOCDMenu           matlab.ui.container.Menu
        CheckPathsMenu        matlab.ui.container.Menu
        AggiornaSoftwareMenu  matlab.ui.container.Menu
        SelezionaSerialeMenu  matlab.ui.container.Menu
        RunningLabel          matlab.ui.control.Label
        StartSendDataButton   matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Timer;
        Time;
        SerialPort;
        Iteration=1;
        TimePassed=0;
        Cmake_Path="";
        OpenOCD_Path="";
        Make_Path="";
        Gcc_Path="";
        ArmNone_Path="";
        Scripts_Path="";
        Main_Path="";
        filename='Settings.mat';
        TxData;
        InputSent=1;
        lenght
        %t = 0:0.02
        T_FR= timeseries(0,0.02);
        T_FL= timeseries(0,0.02);
        T_RR= timeseries(0,0.02);
        T_RL= timeseries(0,0.02);
%         exitFlag= timeseries(0,0.02);
    end

    methods (Access = private)
        
        function readSerialData(app,src,~)
            app.Iteration=app.Iteration+1;
            if(app.Iteration>1)
                app.TimePassed(app.Iteration)=app.TimePassed(app.Iteration-1)+0.02;
            end
            raw = readline(src);
            data = double(uint8(raw{1}));
            app.Time(app.Iteration)=data(2)+data(1)*256;
            
            %Inizializzo quattro vettori per le quatttro coppie
            T_FR_VCU=zeros(1,8);
            T_FL_VCU=zeros(1,8);
            T_RR_VCU=zeros(1,8);
            T_RL_VCU=zeros(1,8);

            %Vado a riempire i quattro vettori ognuno con i bytes che andrò
            %poi ad interpretare come un'unica codifica di 64 bit (double)
            %nel momento in cui vado ad aggiungere un sample alla
            %corrispondente timeseries
            j=1;
            for i = 3:10
                T_FR_VCU(j)=data(i);
                j=j+1;
            end
            app.T_FR = addsample(app.T_FR,'Data',typecast(uint8(T_FR_VCU), 'double'),'Time',app.TimePassed(app.Iteration));
            j=1;
            for i = 11:18
                T_FL_VCU(j)=data(i);
                j=j+1;
            end
            app.T_FL = addsample(app.T_FL,'Data',typecast(uint8(T_FL_VCU), 'double'),'Time',app.TimePassed(app.Iteration));
            j=1;
            for i = 19:26  
                T_RR_VCU(j)=data(i);
                j=j+1;
            end
            app.T_RR = addsample(app.T_RR,'Data',typecast(uint8(T_RR_VCU), 'double'),'Time',app.TimePassed(app.Iteration));
            j=1;
            for i = 27:34 
                T_RL_VCU(j)=data(i);
                j=j+1;
             end
             app.T_RL = addsample(app.T_RL,'Data',typecast(uint8(T_RL_VCU), 'double'),'Time',app.TimePassed(app.Iteration));

%              app.exitFlag=addsample(app.exitFlag,'Data',data(35),'Time',app.TimePassed(app.Iteration));

            if(app.Iteration>300)
                plot(app.UIAxes,app.TimePassed(app.Iteration-300:app.Iteration),app.Time(app.Iteration-300:app.Iteration));
            else
                plot(app.UIAxes,app.TimePassed,app.Time);
            end
            if app.InputSent<app.lenght
                %DRIVER INPUTS
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,app.TxData.throttle.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.steering.Data(app.InputSent),"double");
%                 %DYNAMICS INPUTS
%                 write(app.SerialPort,app.TxData.vx.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.vy.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.ax.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.ay.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.yaw_rate.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_FL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_FR.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_RL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_RR.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_FL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_FR.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_RL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_RR.Data(app.InputSent),"double");
%             pause(0.05);
%             write(app.SerialPort,[app.TxData.throttle.Data(app.InputSent) ...
%                 app.TxData.brake.Data(app.InputSent) ...
%                 app.TxData.steering.Data(app.InputSent) ...
%                 app.TxData.vx.Data(app.InputSent) ...
%                 app.TxData.vy.Data(app.InputSent) ...
%                 app.TxData.ax.Data(app.InputSent) ...
%                 app.TxData.ay.Data(app.InputSent) ...
%                 app.TxData.yaw_rate.Data(app.InputSent) ...
%                 app.TxData.brake_torque_FL.Data(app.InputSent) ...
%                 app.TxData.brake_torque_FR.Data(app.InputSent) ...
%                 app.TxData.brake_torque_RL.Data(app.InputSent) ...
%                 app.TxData.brake_torque_RR.Data(app.InputSent) ...
%                 app.TxData.omega_wheels_FL.Data(app.InputSent) ...
%                 app.TxData.omega_wheels_FR.Data(app.InputSent) ...
%                 app.TxData.omega_wheels_RL.Data(app.InputSent) ...
%                 app.TxData.omega_wheels_RR.Data(app.InputSent)],"double");
                
%                 app.InputSent=app.InputSent+1;
            else
                app.InputSent=1;
                app.RunningLabel.FontColor=[255 165 0]/255;
                app.RunningLabel.Text="Waiting";
                %Quando ho finito una risoluzione del problema genero nel
                %workspace le quattro timeseries d'interesse (coppie)
                assignin("base","T_FR",app.T_FR);
                assignin("base","T_FL",app.T_FL);
                assignin("base","T_RR",app.T_RR);
                assignin("base","T_RL",app.T_RL);
                %assignin("base","exitFlag",app.exitFlag);
            end

        end
        
        function Status=CheckPath(app,path,CallerFunction,typeCheck)   
            if(path~="")
                if (typeCheck=="Cmake Path")
                    sysout=system("if exist """+ path+"\cmake.exe""" +" (exit /b 1)  else (exit /b 2)");
                    if(sysout==1)
                        Status="";
                        app.Cmake_Path=path;
                        Cmake=app.Cmake_Path;
                        save(app.filename,'Cmake','-append');
                    else
                        Status="Cmake Path  ";
                        if (CallerFunction=="Startup")
                            app.Cmake_Path="";
                        end
                    end
                elseif (typeCheck=="OpenOCD Path")
                    sysout=system("if exist """+ path+"\openocd.exe""" +" (exit /b 1)  else (exit /b 2)");
                    if(sysout==1)
                        Status="";
                        app.OpenOCD_Path=path;
                        OpenOCD=app.OpenOCD_Path;
                        save(app.filename,'OpenOCD','-append');
                    else
                        Status="OpenOCD Path  ";
                        if (CallerFunction=="Startup")
                            app.OpenOCD_Path="";
                        end
                    end
                elseif (typeCheck=="Make Path")
                    sysout=system("if exist """+ path+"\make.exe""" +" (exit /b 1)  else (exit /b 2)");
                    if(sysout==1)
                        Status="";
                        app.Make_Path=path;
                        Make=app.Make_Path;
                        save(app.filename,'Make','-append');
                    else
                        Status="Make Path  ";
                        if (CallerFunction=="Startup")
                            app.Make_Path="";
                        end
                    end
                elseif (typeCheck=="Gcc Path")
                    sysout=system("if exist """+ path+"\gcc.exe""" +" (exit /b 1)  else (exit /b 2)");
                    if(sysout==1)
                        Status="";
                        app.Gcc_Path=path;
                        Gcc=app.Gcc_Path;
                        save(app.filename,'Gcc','-append');
                    else
                        Status="Gcc Path  ";
                        if (CallerFunction=="Startup")
                            app.Gcc_Path="";
                        end
                    end
                elseif (typeCheck=="ArmNone Path")
                    sysout=system("if exist """+ path+"\arm-none-eabi-gcc.exe""" +" (exit /b 1)  else (exit /b 2)");
                    if(sysout==1)
                        Status="";
                        app.ArmNone_Path=path;
                        ArmNone=app.ArmNone_Path;
                        save(app.filename,'ArmNone','-append');
                    else
                        Status="ArmNone Path  ";
                        if (CallerFunction=="Startup")
                            app.Gcc_Path="";
                        end
                    end
                end     
            else 
                 if (typeCheck=="Cmake Path")
                     Status="Cmake Path  ";
                     if (CallerFunction=="Startup")
                        app.Cmake_Path="";
                     end
                 elseif (typeCheck=="OpenOCD Path")
                     Status="OpenOCD Path  ";
                     if (CallerFunction=="Startup")
                        app.OpenOCD_Path="";
                     end
                 elseif (typeCheck=="Make Path")
                     Status="Make Path  ";
                     if (CallerFunction=="Startup")
                        app.Make_Path="";
                     end
                 elseif (typeCheck=="Gcc Path")
                     Status="Gcc Path  ";
                     if (CallerFunction=="Startup")
                        app.Gcc_Path="";
                     end
                 elseif (typeCheck=="ArmNone Path")
                     Status="ArmNone Path  ";
                     if (CallerFunction=="Startup")
                        app.Gcc_Path="";
                     end
                 end
            end
        end
        
        function SerialCheck(app)
            freeports=serialportlist("available");
            if(freeports~="")
                if(length(freeports)>1);[index,~]=listdlg('ListString',freeports);else;index=1;end
                app.SerialPort = serialport(freeports(index),115800);
                configureTerminator(app.SerialPort,"CR/LF","CR/LF");
                configureCallback(app.SerialPort,"terminator",@(src, event) readSerialData(app,src,event));
            else 
                errordlg("No Serial Port Connected");
            end     
            
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RunningLabel.FontColor=[255 165 0]/255;
            app.RunningLabel.Text="Waiting";
            app.Main_Path=pwd;
            app.Main_Path=pwd;
            app.Cmake_Path=load(app.filename).Cmake;
            Status1=CheckPath(app,app.Cmake_Path,"Startup","Cmake Path");
            app.OpenOCD_Path=load(app.filename).OpenOCD;
            Status2=CheckPath(app,app.OpenOCD_Path,"Startup","OpenOCD Path");
            app.Make_Path=load(app.filename).Make;
            Status3=CheckPath(app,app.Make_Path,"Startup","Make Path");
            app.Gcc_Path=load(app.filename).Gcc;
            Status4=CheckPath(app,app.Gcc_Path,"Startup","Gcc Path");
            app.ArmNone_Path=load(app.filename).ArmNone;
            Status5=CheckPath(app,app.ArmNone_Path,"Startup","ArmNone Path");
            app.Scripts_Path=fullfile(app.OpenOCD_Path,"..\share\openocd\scripts");
            if ( Status1~="" || Status2~="" || Status3~="" || Status4~="" || Status5~="")
                errordlg("problem with "+Status1+Status2+Status3+Status4+Status5);
            end
            app.SerialCheck();
            ylabel(app.UIAxes,'[us]') 
            xlabel(app.UIAxes,'[s]') 
            app.TxData=load("Open_Loop_Input_Output.mat");
            app.lenght=length(app.TxData.steering.Data);
            app.Timer = timer('TimerFcn',@app.TimerCallback,'StartDelay',0,'Period',0.01,'ExecutionMode','fixedSpacing');
            
        end

        % Menu selected function: AggiornaSoftwareMenu
        function AggiornaSoftwareMenuSelected(app, event)
            if(app.Cmake_Path~="")
                mkdir CodiceGenerato\;
                cd CodiceGenerato\;
                slbuild("TVModel");
                cd ..;
                copyfile CodiceGenerato\TVModel_ert_rtw\TVModel.c ITB_Nucleo_configuration\C_Code_Sources\Src;
                copyfile CodiceGenerato\TVModel_ert_rtw\TVModel.h ITB_Nucleo_configuration\C_Code_Sources\Inc;
                copyfile CodiceGenerato\TVModel_ert_rtw\rtwtypes.h ITB_Nucleo_configuration\C_Code_Sources\Inc;
                cd ITB_Nucleo_configuration\;
                sysout=system("git submodule update --init --recursive");
                 if(sysout~=0); errordlg("Error in git configuration"+newline+"Ask E&P department");cd ..;return;end
                copyfile cmake\CMakeLists.txt acados;
                mkdir cmake-build-debug\;
                %cd cmake-build-debug\;
                sysout=system(""""+app.Cmake_Path+"\cmake.exe"""+" -DCMAKE_BUILD_TYPE=Debug  -G Ninja  -S"""+app.Main_Path+"\ITB_Nucleo_configuration"" -B """+app.Main_Path+"\ITB_Nucleo_configuration\cmake-build-debug"" Toolchain directory: """+app.ArmNone_Path+"""");
                if(sysout~=0); errordlg("Error during Cmake build,"+newline+"Ask E&P department");cd ..;return;end
                sysout=system(""""+app.Cmake_Path+"\cmake.exe"""+" --build """+app.Main_Path+"\ITB_Nucleo_configuration\cmake-build-debug"" --target ITB_Nucleo_configuration.elf -- -j 6");
                if(sysout~=0); errordlg("Error during build,"+newline+"Ask E&P department");cd ..;return;end
                sysout=system(""""+app.OpenOCD_Path+"\openocd.exe"""+" -s """+app.Scripts_Path+""" -f """+app.Main_Path+"\ITB_Nucleo_configuration\cmake\openocd.cfg"" -c ""tcl_port disabled"" -c ""gdb_port disabled"" -c ""tcl_port disabled"" -c ""program \"""+replace(app.Main_Path,'\','/')+"/ITB_Nucleo_configuration/cmake-build-debug/ITB_Nucleo_configuration.elf\"""" -c reset -c shutdown");
                if(sysout~=0); errordlg("Error during flash,"+newline+"verify that USB is connected");cd ..;return;end
            else
                errordlg("Keil path is wrong,"+newline+"invalid path");
            end
        end

        % Menu selected function: SelezionaSerialeMenu
        function SelezionaSerialeMenuSelected(app, event)
            app.SerialCheck();
        end

        % Button pushed function: StartSendDataButton
        function StartSendDataButtonPushed(app, event)

                %DRIVER INPUTS
%                 write(app.SerialPort,[3 3],"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,3,"double");
%                 write(app.SerialPort,app.TxData.throttle.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.steering.Data(app.InputSent),"double");
%                 %DYNAMICS INPUTS
%                 write(app.SerialPort,app.TxData.vx.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.vy.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.ax.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.ay.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.yaw_rate.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_FL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_FR.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_RL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.brake_torque_RR.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_FL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_FR.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_RL.Data(app.InputSent),"double");
%                 write(app.SerialPort,app.TxData.omega_wheels_RR.Data(app.InputSent),"double");

write(app.SerialPort,[app.TxData.throttle.Data(app.InputSent) ...
    app.TxData.brake.Data(app.InputSent) ...
    app.TxData.steering.Data(app.InputSent) ...
    app.TxData.vx.Data(app.InputSent) ...
    app.TxData.vy.Data(app.InputSent) ...
    app.TxData.ax.Data(app.InputSent) ...
    app.TxData.ay.Data(app.InputSent) ...
    app.TxData.yaw_rate.Data(app.InputSent) ...
    app.TxData.brake_torque_FL.Data(app.InputSent) ...
    app.TxData.brake_torque_FR.Data(app.InputSent) ...
    app.TxData.brake_torque_RL.Data(app.InputSent) ...
    app.TxData.brake_torque_RR.Data(app.InputSent) ...
    app.TxData.omega_wheels_FL.Data(app.InputSent) ...
    app.TxData.omega_wheels_FR.Data(app.InputSent) ...
    app.TxData.omega_wheels_RL.Data(app.InputSent) ...
    app.TxData.omega_wheels_RR.Data(app.InputSent)],"double");
                app.InputSent=app.InputSent+1;  
                
            app.RunningLabel.FontColor="Green";
            app.RunningLabel.Text="Running";
        end

        % Menu selected function: CmakeMenu
        function CmakeMenuSelected(app, event)
            path=inputdlg("Write Cmake installation path (where there is cmake.exe file)","DefinePath",1,app.Cmake_Path);
            Status=CheckPath(app,path,"Menu","Cmake Path");
            if (Status~="")
                errordlg("problem with "+Status);
            end
        end

        % Menu selected function: MakeMenu
        function MakeMenuSelected(app, event)
            path=inputdlg("Write make installation path (where there is make.exe file)","DefinePath",1,app.Make_Path);
            Status=CheckPath(app,path,"Menu","Make Path");
            if (Status~="")
                errordlg("problem with "+Status);
            end
        end

        % Menu selected function: GccMenu
        function GccMenuSelected(app, event)
            path=inputdlg("Write Gcc installation path (where there is gcc.exe file)","DefinePath",1,app.Gcc_Path);
            Status=CheckPath(app,path,"Menu","Gcc Path");
            if (Status~="")
                errordlg("problem with "+Status);
            end
        end

        % Menu selected function: OpenOCDMenu
        function OpenOCDMenuSelected(app, event)
            path=inputdlg("Write OpenOCD installation path (where there is openocd.exe file)","DefinePath",1,app.OpenOCD_Path);
            Status=CheckPath(app,path,"Menu","OpenOCD Path");
            if (Status~="")
                errordlg("problem with "+Status);
            end
        end

        % Menu selected function: CheckPathsMenu
        function CheckPathsMenuSelected(app, event)
            Status1=CheckPath(app,app.Cmake_Path,"Menu","Cmake Path");
            Status2=CheckPath(app,app.Make_Path,"Menu","Make Path");
            Status3=CheckPath(app,app.Gcc_Path,"Menu","Gcc Path");
            Status4=CheckPath(app,app.OpenOCD_Path,"Menu","OpenOCD Path");
            Status5=CheckPath(app,app.ArmNone_Path,"Menu","ArmNone Path");
            if ( Status1~="" || Status2~="" || Status3~="" || Status4~="" || Status5~="")
                errordlg("problem with "+Status1+Status2+Status3+Status4+Status5);
            end
        end

        % Menu selected function: ArmNoneEabiMenu
        function ArmNoneEabiMenuSelected(app, event)
            path=inputdlg("Write Arm-None-eabi installation path (where there is arm-none-eabi-gcc.exe file)","DefinePath",1,app.ArmNone_Path);
            Status=CheckPath(app,path,"Menu","ArmNone Path");
            if (Status~="")
                errordlg("problem with "+Status);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImpostazioniMenu = uimenu(app.UIFigure);
            app.ImpostazioniMenu.Text = 'Impostazioni';
            app.PathsMenu = uimenu(app.ImpostazioniMenu);
            app.PathsMenu.Text = 'Paths';
            app.CmakeMenu = uimenu(app.PathsMenu);
            app.CmakeMenu.MenuSelectedFcn = createCallbackFcn(app, @CmakeMenuSelected, true);
            app.CmakeMenu.Text = 'Cmake';
            app.MakeMenu = uimenu(app.PathsMenu);
            app.MakeMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeMenuSelected, true);
            app.MakeMenu.Text = 'Make';
            app.ArmNoneEabiMenu = uimenu(app.PathsMenu);
            app.ArmNoneEabiMenu.MenuSelectedFcn = createCallbackFcn(app, @ArmNoneEabiMenuSelected, true);
            app.ArmNoneEabiMenu.Text = 'Arm-None-Eabi';
            app.GccMenu = uimenu(app.PathsMenu);
            app.GccMenu.MenuSelectedFcn = createCallbackFcn(app, @GccMenuSelected, true);
            app.GccMenu.Text = 'Gcc';
            app.OpenOCDMenu = uimenu(app.PathsMenu);
            app.OpenOCDMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenOCDMenuSelected, true);
            app.OpenOCDMenu.Text = 'OpenOCD';
            app.CheckPathsMenu = uimenu(app.PathsMenu);
            app.CheckPathsMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckPathsMenuSelected, true);
            app.CheckPathsMenu.Text = 'CheckPaths';
            app.AggiornaSoftwareMenu = uimenu(app.ImpostazioniMenu);
            app.AggiornaSoftwareMenu.MenuSelectedFcn = createCallbackFcn(app, @AggiornaSoftwareMenuSelected, true);
            app.AggiornaSoftwareMenu.Text = 'AggiornaSoftware';
            app.SelezionaSerialeMenu = uimenu(app.ImpostazioniMenu);
            app.SelezionaSerialeMenu.MenuSelectedFcn = createCallbackFcn(app, @SelezionaSerialeMenuSelected, true);
            app.SelezionaSerialeMenu.Text = 'Seleziona Seriale';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartSendDataButton = uibutton(app.UIFigure, 'push');
            app.StartSendDataButton.ButtonPushedFcn = createCallbackFcn(app, @StartSendDataButtonPushed, true);
            app.StartSendDataButton.Text = 'StartSendData';
            app.RunningLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialVisualizator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end